classdef timetagger01 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        settingsTab                     matlab.ui.container.Tab
        n_channels                      matlab.ui.control.Label
        n_serial                        matlab.ui.control.Label
        channel_Template                matlab.ui.control.Label
        triglvl_Template                matlab.ui.control.NumericEditField
        delay_Template                  matlab.ui.control.NumericEditField
        deadtime_Template               matlab.ui.control.NumericEditField
        testsignal_Template             matlab.ui.control.CheckBox
        ChannelLabel                    matlab.ui.control.Label
        TriggerlevelVLabel              matlab.ui.control.Label
        DelaypsLabel                    matlab.ui.control.Label
        DeadtimepsLabel                 matlab.ui.control.Label
        testsignalLabel                 matlab.ui.control.Label
        messageLabel                    matlab.ui.control.Label
        countratecpsLabel               matlab.ui.control.Label
        countrate_Template              matlab.ui.control.Label
        timeoutLabel                    matlab.ui.control.Label
        SavesettingsButton              matlab.ui.control.Button
        LoadsettingsButton              matlab.ui.control.Button
        HelpButton                      matlab.ui.control.Button
        plotTab                         matlab.ui.container.Tab
        TabGroup2                       matlab.ui.container.TabGroup
        singleTab                       matlab.ui.container.Tab
        channelAEditFieldLabel          matlab.ui.control.Label
        channelAEditField               matlab.ui.control.NumericEditField
        channelBEditFieldLabel          matlab.ui.control.Label
        channelBEditField               matlab.ui.control.NumericEditField
        singleAxes                      matlab.ui.control.UIAxes
        maximumLabel                    matlab.ui.control.Label
        MaxvalueLabel                   matlab.ui.control.Label
        autoyaxisCheckBox               matlab.ui.control.CheckBox
        ymaxLabel                       matlab.ui.control.Label
        ymaxEditField                   matlab.ui.control.NumericEditField
        PositonpsLabel                  matlab.ui.control.Label
        positionLabel                   matlab.ui.control.Label
        multiTab                        matlab.ui.container.Tab
        UIAxes1                         matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes3                         matlab.ui.control.UIAxes
        UIAxes4                         matlab.ui.control.UIAxes
        chA1EditFieldLabel              matlab.ui.control.Label
        chA1EditField                   matlab.ui.control.NumericEditField
        chB1EditFieldLabel              matlab.ui.control.Label
        chB1EditField                   matlab.ui.control.NumericEditField
        chA2EditFieldLabel              matlab.ui.control.Label
        chA2EditField                   matlab.ui.control.NumericEditField
        chB2EditFieldLabel              matlab.ui.control.Label
        chB2EditField                   matlab.ui.control.NumericEditField
        chA3EditFieldLabel              matlab.ui.control.Label
        chA3EditField                   matlab.ui.control.NumericEditField
        chB3EditFieldLabel              matlab.ui.control.Label
        chB3EditField                   matlab.ui.control.NumericEditField
        chA4EditFieldLabel              matlab.ui.control.Label
        chA4EditField                   matlab.ui.control.NumericEditField
        chB4EditFieldLabel              matlab.ui.control.Label
        chB4EditField                   matlab.ui.control.NumericEditField
        multiplotenableCheckBox         matlab.ui.control.CheckBox
        countsTab                       matlab.ui.container.Tab
        chButton_Template               matlab.ui.control.StateButton
        Panel1                          matlab.ui.container.Panel
        channelAEditField_2Label        matlab.ui.control.Label
        count1channelAEditField         matlab.ui.control.NumericEditField
        channelBEditField_2Label        matlab.ui.control.Label
        count1channelBEditField         matlab.ui.control.NumericEditField
        count1rateA                     matlab.ui.control.Label
        count1rateB                     matlab.ui.control.Label
        count1coincidence               matlab.ui.control.Label
        count1EnableCheckBox            matlab.ui.control.CheckBox
        Panel2                          matlab.ui.container.Panel
        channelAEditField_2Label_2      matlab.ui.control.Label
        count2channelAEditField         matlab.ui.control.NumericEditField
        channelBEditField_2Label_2      matlab.ui.control.Label
        count2channelBEditField         matlab.ui.control.NumericEditField
        count2rateA                     matlab.ui.control.Label
        count2rateB                     matlab.ui.control.Label
        count2coincidence               matlab.ui.control.Label
        count2EnableCheckBox            matlab.ui.control.CheckBox
        Panel3                          matlab.ui.container.Panel
        channelAEditField_2Label_3      matlab.ui.control.Label
        count3channelAEditField         matlab.ui.control.NumericEditField
        channelBEditField_2Label_3      matlab.ui.control.Label
        count3channelBEditField         matlab.ui.control.NumericEditField
        count3rateA                     matlab.ui.control.Label
        count3rateB                     matlab.ui.control.Label
        count3coincidence               matlab.ui.control.Label
        count3EnableCheckBox            matlab.ui.control.CheckBox
        Panel4                          matlab.ui.container.Panel
        channelAEditField_2Label_4      matlab.ui.control.Label
        count4channelAEditField         matlab.ui.control.NumericEditField
        channelBEditField_2Label_4      matlab.ui.control.Label
        count4channelBEditField         matlab.ui.control.NumericEditField
        count4rateA                     matlab.ui.control.Label
        count4rateB                     matlab.ui.control.Label
        count4coincidence               matlab.ui.control.Label
        count4EnableCheckBox            matlab.ui.control.CheckBox
        virtualchannelTab               matlab.ui.container.Tab
        VirtualchannelstateTextAreaLabel  matlab.ui.control.Label
        VirtualchannelstateTextArea     matlab.ui.control.TextArea
        CommandWindowEditFieldLabel     matlab.ui.control.Label
        CommandWindowEditField          matlab.ui.control.EditField
        AddVirtualchannelPanel          matlab.ui.container.Panel
        addmodeDropDownLabel            matlab.ui.control.Label
        addmodeDropDown                 matlab.ui.control.DropDown
        addchannelAEditFieldLabel       matlab.ui.control.Label
        addchannelAEditField            matlab.ui.control.NumericEditField
        addchannelBEditFieldLabel       matlab.ui.control.Label
        addchannelBEditField            matlab.ui.control.NumericEditField
        autodelayCheckBox               matlab.ui.control.CheckBox
        AddButton                       matlab.ui.control.Button
        windowEditFieldLabel            matlab.ui.control.Label
        windowEditField                 matlab.ui.control.NumericEditField
        GatedVirtualchannelPanel        matlab.ui.container.Panel
        gatestartchannelEditFieldLabel  matlab.ui.control.Label
        gatestartchannelEditField       matlab.ui.control.NumericEditField
        gatestopchannelEditFieldLabel   matlab.ui.control.Label
        gatestopchannelEditField        matlab.ui.control.NumericEditField
        AddButton_2                     matlab.ui.control.Button
        channeltogateEditFieldLabel     matlab.ui.control.Label
        channeltogateEditField          matlab.ui.control.NumericEditField
        DelayedchannelPanel             matlab.ui.container.Panel
        channeltodelayEditFieldLabel    matlab.ui.control.Label
        channeltodelayEditField         matlab.ui.control.NumericEditField
        addchangedelayEditFieldLabel    matlab.ui.control.Label
        addchangedelayEditField         matlab.ui.control.NumericEditField
        AddButton_3                     matlab.ui.control.Button
        stateLabel                      matlab.ui.control.Label
        CommandfeedbackLabel            matlab.ui.control.Label
        UITable                         matlab.ui.control.Table
        LogTab                          matlab.ui.container.Tab
        startlogButton                  matlab.ui.control.Button
        clearlogButton                  matlab.ui.control.Button
        stoplogButton                   matlab.ui.control.Button
        logaxes                         matlab.ui.control.UIAxes
        countrateofchannelEditFieldLabel  matlab.ui.control.Label
        countrateofchannelEditField     matlab.ui.control.NumericEditField
        OverflowsLamp                   matlab.ui.control.Lamp
        ClearoverflowButton             matlab.ui.control.Button
        OverflowsnumberLabel            matlab.ui.control.Label
        startButton                     matlab.ui.control.Button
        stopButton                      matlab.ui.control.Button
        saveButton                      matlab.ui.control.Button
        PeriodsEditFieldLabel           matlab.ui.control.Label
        PeriodsEditField                matlab.ui.control.NumericEditField
        binwidthpsEditFieldLabel        matlab.ui.control.Label
        binwidthpsEditField             matlab.ui.control.NumericEditField
        binnumberEditFieldLabel         matlab.ui.control.Label
        binnumberEditField              matlab.ui.control.NumericEditField
        windowwidthnsEditFieldLabel     matlab.ui.control.Label
        windowwidthnsEditField          matlab.ui.control.NumericEditField
        autosaveButton                  matlab.ui.control.StateButton
    end

    
    properties (Access = private)
        % 常数
        channels             % number of channels
        LINEHEIGHT = 25;     % line height for each channel
        CH_OFF = -1;         % channel offset, Time Tagger channels start with 0, matlab arrays with 1 - so we need a channel offset
        f_connection = 0;    % flag of connection 可以用一个指示灯来代替？
        formatString = '%0.3g';  % 数字的输出格式
        channelMatrix            % 通道按钮矩阵的开启按下状态
        flag_buttonMatrix =0;    % 是否启用按钮矩阵的标志变量
        virtuali = 0;            % 虚拟通道index
        countrateVirtualNumber   % countratrvirtual对象的通道数寄存变量
        countrateVirtualRate     % countratrvirtual对象的计数率寄存变量
        timeoutCount =0;         % 统计超时次数
        displayCorrelationMeasurement    %当前single plot图像的数据
        autoSaveIndex =0;        % index of auto save period number
        autoSaveName             % the current auto save file name
        
        % 对象
        timetagger           % Time Tagger object
        countrate            % Countrate measurement class
        countrateVirtual     % 虚拟通道的计数率对象
        theTimer             % the timer obj
        correlationSingle    % correlation对象（元胞数组）
        correlationMulti
        correlationCount
        virtualChannel_         % 虚拟通道对象（元胞数组）
        
        % 虚拟通道参数表
        virtualTableIndex          % all virtual channel's index
        virtualTableNumber         % all virtual channel's number.
        virtualTableType="";        % all virtual channel's type.
        virtualTableName="";        % all virtual channel's name
        
        % 控件对象（元胞数组）
        channel_
        triglvl_
        delay_
        deadtime_
        testsignal_
        countrate_
        chButton_
        
        % log相关
        flag_log = 0;
        x_time
        y_countrate
    end
    
    methods (Access = private)
        
        function initialization(app)         %初始化
            clc;
            try                         %连接仪器
                app.timetagger = TimeTagger;
                app.f_connection=1;
                app.messageLabel.Text = 'Connected';
            catch
                app.messageLabel.Text = 'Not connected';
                app.messageLabel.FontColor = [1 0 0];
                app.f_connection=0;
                return
            end
            app.timetagger.reset();
            set(app.n_serial, 'text', ['Serial number: ',num2str(app.timetagger.getSerial())])
            app.channels = app.timetagger.getChannels() / 2;
            set(app.n_channels, 'text', [num2str(app.channels),' channels']);
            createUiElementsFromTemplate(app)
            retrieveSettings(app);
            
            app.countrate = TTCountrate(app.timetagger, (1:app.channels) + app.CH_OFF); %显示setting页面的计数率
            overflowstate(app)
            updateCorrelationSettings(app);
            app.channelMatrix = zeros(app.channels);
            app.ymaxEditField.Visible = 'off';    %y轴上限输入框默认不可见
            app.ymaxLabel.Visible = 'off';
            
            %             app.channelAEditField.Limits = [0 double(app.channels)-1];
            %             app.channelBEditField.Limits = [0 double(app.channels)-1];
            %             app.chA1EditField.Limits = [0 double(app.channels)-1];
            %             app.chB1EditField.Limits = [0 double(app.channels)-1];
            %             app.chA2EditField.Limits = [0 double(app.channels)-1];
            %             app.chB2EditField.Limits = [0 double(app.channels)-1];
            %             app.chA3EditField.Limits = [0 double(app.channels)-1];
            %             app.chB3EditField.Limits = [0 double(app.channels)-1];
            %             app.chA4EditField.Limits = [0 double(app.channels)-1];
            %             app.chB4EditField.Limits = [0 double(app.channels)-1];
            %             app.multiplotenableCheckBox.Value
        end
        
        function overflowstate(app)
            n_overflows = app.timetagger.getOverflows();
            if n_overflows > 0
                set(app.OverflowsLamp, 'Color', [1 0 0]);
                set(app.OverflowsnumberLabel, 'Text', ['Overflows: ', num2str(n_overflows)]);
            elseif n_overflows == 0
                set(app.OverflowsLamp, 'Color', [0 1 0]);
                set(app.OverflowsnumberLabel, 'Text', 'Overflows: 0');
            else
                disp('number of overflows error')
            end
        end
        
        function retrieveSettings(app)
            % 此函数作用：在程序刚刚运行时，读取仪器上的设置并恢复到软件的设置页面
            for i = 1: app.channels
                app.testsignal_{i}.Value = app.timetagger.getTestSignal(i + app.CH_OFF);
                app.triglvl_{i}.Value = double(app.timetagger.getTriggerLevel(i + app.CH_OFF));
                app.delay_{i}.Value = double(app.timetagger.getInputDelay(i + app.CH_OFF));
                app.deadtime_{i}.Value = double(app.timetagger.getDeadtime(i + app.CH_OFF));
            end
        end
        
        %*********************************  timer callback  ******************************
        
        % 下面几个函数用来更新数据，参数设置，画图
        function updatetimerCallback(app)
            % 更新数据，timer的回调函数
            
            % 显示timer的超时数
            % 如果上次的运行间隔大于设定间隔的1.1倍，则算做超时。（不能按照1.0倍来比较计算）
            if ~isnan(app.theTimer.InstantPeriod) && app.theTimer.InstantPeriod > app.PeriodsEditField.Value*1.1
                app.timeoutCount = app.timeoutCount + 1;
            end
            if app.timeoutCount > 0
                app.timeoutLabel.Visible = 'on';
                app.timeoutLabel.Text = ['time out number: ', num2str(app.timeoutCount),', time out rate: ',num2str(round(app.timeoutCount/app.theTimer.TasksExecuted*100)),'%'];
            end
            
            % 检测overflows
            overflowstate(app);
            
            % 更新countrate
            updateCountrateTimerCallback(app);
            
            % 更新虚拟通道的countrate
            if ~isempty(app.countrateVirtualNumber)
                updateCountrateVirtualCallback(app);
            end
            
            % 画图single
            plotCorrelatonSingle(app)
            
            % 画图multi
            if app.multiplotenableCheckBox.Value
                plotCorrelationMulti(app);
            end
            
            % 更新counts页面中的几个panel
            updateCountsPanel1(app);
            updateCountsPanel2(app);
            updateCountsPanel3(app);
            updateCountsPanel4(app);
            
            % log
            if app.flag_log
                timelog(app);
            end
            
        end


        % ************************************  plot Correlaton **********************************
        function plotCorrelatonSingle(app)
            % 画correlationSingle
            x0 = app.correlationSingle.getIndex();
            y0 = app.correlationSingle.getData();
            app.correlationSingle.clear();
            if size(x0,2) ~= size(y0,2)
                % settings have changed between the getIndex() and getData()
                return
            end
            plot(app.singleAxes, x0,y0)
            if app.autoyaxisCheckBox.Value
                maxvalue = max(y0);
            else
                maxvalue = app.ymaxEditField.Value;
            end
            app.maximumLabel.Text = sprintf(app.formatString, max(y0));
            position = 0;
            if max(y0)>0
                position = x0(y0==max(y0));
            end
            app.positionLabel.Text = sprintf(app.formatString, position);
            if maxvalue == 0
                ylim(app.singleAxes, [0 1])
            else
                ylim(app.singleAxes, [0 maxvalue*1.1])
            end
            % 下面这几行用来把保存当前plot显示的数据
            duration = app.correlationSingle.getCaptureDuration();
            time = datetime;
            app.displayCorrelationMeasurement.x = x0;
            app.displayCorrelationMeasurement.counts = y0;
            app.displayCorrelationMeasurement.duration = duration;
            app.displayCorrelationMeasurement.chA = app.channelAEditField.Value;
            app.displayCorrelationMeasurement.chB = app.channelBEditField.Value;
            app.displayCorrelationMeasurement.binnumber = app.binnumberEditField.Value;
            app.displayCorrelationMeasurement.binWidth = app.binwidthpsEditField.Value;
            app.displayCorrelationMeasurement.saveTime = time;
            % The following lines of code are used to auto save
            if app.autosaveButton.Value
                variableName = ['correlation_',num2str(app.autoSaveIndex)];
                eval([variableName, '=app.displayCorrelationMeasurement;']);
                if app.autoSaveIndex == 1
                    formatOut = 'yyyy-mm-dd_HH-MM-SS';
                    time = datetime;
                    ds = datestr(time, formatOut);
                    app.autoSaveName = ['Correlation_autosave_',ds,'.mat'];
                    save(app.autoSaveName, variableName);
                    % save the 4 pairs multi channel
                    for i=1:4
                        multiChannel.chA(i) = eval(['app.chA',num2str(i),'EditField.Value;']);
                        multiChannel.chB(i) = eval(['app.chB',num2str(i),'EditField.Value;']);
                    end
                    save(app.autoSaveName, 'multiChannel','-append');
                else
                    save(app.autoSaveName, variableName,'-append');
                end
                app.autoSaveIndex = app.autoSaveIndex + 1;
            end
        end
        
        function plotCorrelationMulti(app)
            % 画correlationMulti
            for i=1:4
                x0 = app.correlationMulti{i}.getIndex();
                y0 = app.correlationMulti{i}.getData();
                app.correlationMulti{i}.clear();
                if size(x0,2) ~= size(y0,2)
                    % settings have changed between the getIndex() and getData()
                    return
                end
                eval(['plot(app.UIAxes',num2str(i),', x0,y0)']);
                maxvalue = max(y0);
                if maxvalue == 0
                    eval(['ylim(app.UIAxes',num2str(i),', [0 1])']);
                else
                    eval(['ylim(app.UIAxes',num2str(i),', [0 maxvalue*1.1])']);
                end
                % auto save multi
                CorrelationMeasurement.x = x0;
                CorrelationMeasurement.counts = y0;
                if app.autosaveButton.Value
                    variableName = ['correlation_multi_', num2str(i), '_',num2str(app.autoSaveIndex-1)];
                    eval([variableName, '=CorrelationMeasurement;']);
                    save(app.autoSaveName, variableName,'-append')
                end
            end
        end
        
        % *************************************** update correlatin in Counts page  ***********************
        function updateCountsPanel1(app)
            % count页panel 1的符合计数和计数率
            if app.count1EnableCheckBox.Value
                app.count1coincidence.Text = sprintf(app.formatString, max(app.correlationCount{1}.getData()));
                app.correlationCount{1}.clear();
                channelA = app.count1channelAEditField.Value;
                channelB = app.count1channelBEditField.Value;
                if channelA < 2*app.channels      % 如果是真实通道
                    channelA = mod(channelA, app.channels);
                    app.count1rateA.Text = app.countrate_{channelA+1}.Text;
                else
                    if sum(app.countrateVirtualNumber == channelA)  % 如果已经生成了此虚拟通道的计数率对象
                        app.count1rateA.Text = sprintf(app.formatString, app.countrateVirtualRate(app.countrateVirtualNumber == channelA));
                    else       % 此虚拟通道计数率尚未被生成实例
                        % 生成实例，下一周期再显示计数
                        updateCountrateVirtualsettingCallback(app);
                    end
                end
                if channelB < 2*app.channels      % 如果是真实通道
                    channelB = mod(channelB, app.channels);
                    app.count1rateB.Text = app.countrate_{channelB+1}.Text;
                else
                    if sum(app.countrateVirtualNumber == channelB)  % 如果已经生成了此虚拟通道的计数率对象
                        app.count1rateB.Text = sprintf(app.formatString, app.countrateVirtualRate(app.countrateVirtualNumber == channelB));
                    else   % 此虚拟通道计数率尚未被生成实例
                        % 生成实例，下一周期再显示计数
                        updateCountrateVirtualsettingCallback(app);
                    end
                end
            end
        end
        function updateCountsPanel2(app)
            % count页panel 2的符合计数和计数率
            if app.count2EnableCheckBox.Value
                app.count2coincidence.Text = sprintf(app.formatString, max(app.correlationCount{2}.getData()));
                app.correlationCount{2}.clear();
                channelA = app.count2channelAEditField.Value;
                channelB = app.count2channelBEditField.Value;
                if channelA < 2*app.channels      % 如果是真实通道
                    channelA = mod(channelA, app.channels);
                    app.count2rateA.Text = app.countrate_{channelA+1}.Text;
                else
                    if sum(app.countrateVirtualNumber == channelA)  % 如果已经生成了此虚拟通道的计数率对象
                        app.count2rateA.Text = sprintf(app.formatString, app.countrateVirtualRate(app.countrateVirtualNumber == channelA));
                    else       % 此虚拟通道计数率尚未被生成实例
                        % 生成实例，下一周期再显示计数
                        updateCountrateVirtualsettingCallback(app);
                    end
                end
                if channelB < 2*app.channels      % 如果是真实通道
                    channelB = mod(channelB, app.channels);
                    app.count2rateB.Text = app.countrate_{channelB+1}.Text;
                else
                    if sum(app.countrateVirtualNumber == channelB)  % 如果已经生成了此虚拟通道的计数率对象
                        app.count2rateB.Text = sprintf(app.formatString, app.countrateVirtualRate(app.countrateVirtualNumber == channelB));
                    else   % 此虚拟通道计数率尚未被生成实例
                        % 生成实例，下一周期再显示计数
                        updateCountrateVirtualsettingCallback(app);
                    end
                end
            end
        end
        function updateCountsPanel3(app)
            % count页panel 3的符合计数和计数率
            if app.count3EnableCheckBox.Value
                app.count3coincidence.Text = sprintf(app.formatString, max(app.correlationCount{3}.getData()));
                app.correlationCount{3}.clear();
                channelA = app.count3channelAEditField.Value;
                channelB = app.count3channelBEditField.Value;
                if channelA < 2*app.channels      % 如果是真实通道
                    channelA = mod(channelA, app.channels);
                    app.count3rateA.Text = app.countrate_{channelA+1}.Text;
                else
                    if sum(app.countrateVirtualNumber == channelA)  % 如果已经生成了此虚拟通道的计数率对象
                        app.count3rateA.Text = sprintf(app.formatString, app.countrateVirtualRate(app.countrateVirtualNumber == channelA));
                    else       % 此虚拟通道计数率尚未被生成实例
                        % 生成实例，下一周期再显示计数
                        updateCountrateVirtualsettingCallback(app);
                    end
                end
                if channelB < 2*app.channels      % 如果是真实通道
                    channelB = mod(channelB, app.channels);
                    app.count3rateB.Text = app.countrate_{channelB+1}.Text;
                else
                    if sum(app.countrateVirtualNumber == channelB)  % 如果已经生成了此虚拟通道的计数率对象
                        app.count3rateB.Text = sprintf(app.formatString, app.countrateVirtualRate(app.countrateVirtualNumber == channelB));
                    else   % 此虚拟通道计数率尚未被生成实例
                        % 生成实例，下一周期再显示计数
                        updateCountrateVirtualsettingCallback(app);
                    end
                end
            end
        end
        function updateCountsPanel4(app)
            % count页panel 4的符合计数和计数率
            if app.count4EnableCheckBox.Value
                app.count4coincidence.Text = sprintf(app.formatString, max(app.correlationCount{4}.getData()));
                app.correlationCount{4}.clear();
                channelA = app.count4channelAEditField.Value;
                channelB = app.count4channelBEditField.Value;
                if channelA < 2*app.channels      % 如果是真实通道
                    channelA = mod(channelA, app.channels);
                    app.count4rateA.Text = app.countrate_{channelA+1}.Text;
                else
                    if sum(app.countrateVirtualNumber == channelA)  % 如果已经生成了此虚拟通道的计数率对象
                        app.count4rateA.Text = sprintf(app.formatString, app.countrateVirtualRate(app.countrateVirtualNumber == channelA));
                    else       % 此虚拟通道计数率尚未被生成实例
                        % 生成实例，下一周期再显示计数
                        updateCountrateVirtualsettingCallback(app);
                    end
                end
                if channelB < 2*app.channels      % 如果是真实通道
                    channelB = mod(channelB, app.channels);
                    app.count4rateB.Text = app.countrate_{channelB+1}.Text;
                else
                    if sum(app.countrateVirtualNumber == channelB)  % 如果已经生成了此虚拟通道的计数率对象
                        app.count4rateB.Text = sprintf(app.formatString, app.countrateVirtualRate(app.countrateVirtualNumber == channelB));
                    else   % 此虚拟通道计数率尚未被生成实例
                        % 生成实例，下一周期再显示计数
                        updateCountrateVirtualsettingCallback(app);
                    end
                end
            end
        end
        
        % **************************************************** update Correlation Settings *****************************
        % 更新correlation设置
        function updateCorrelationSettings(app)
            % create a new Correlation measurement with the current settings from the gui input fields
            app.correlationSingle = TTCorrelation(app.timetagger, app.channelAEditField.Value, app.channelBEditField.Value, app.binwidthpsEditField.Value, app.binnumberEditField.Value);
            if app.multiplotenableCheckBox.Value
                % 多符合同时开启的时候的代码
                for i=1:4          % 更新四个小plot
                    %从不同的控件那里获得channel number
                    channelA = eval(['app.chA',num2str(i),'EditField.Value;']);
                    channelB = eval(['app.chB',num2str(i),'EditField.Value;']);
                    app.correlationMulti{i} = TTCorrelation(app.timetagger, channelA, channelB, app.binwidthpsEditField.Value, app.binnumberEditField.Value);
                end
            end
        end
        
        % 更新count页面的correlation设置
        function updateCountCorrelationSettings(app)
            if app.count1EnableCheckBox.Value
                app.correlationCount{1} = TTCorrelation(app.timetagger, app.count1channelAEditField.Value, app.count1channelBEditField.Value, app.binwidthpsEditField.Value, app.binnumberEditField.Value);
            end
            if app.count2EnableCheckBox.Value
                app.correlationCount{2} = TTCorrelation(app.timetagger, app.count2channelAEditField.Value, app.count2channelBEditField.Value, app.binwidthpsEditField.Value, app.binnumberEditField.Value);
            end
            if app.count3EnableCheckBox.Value
                app.correlationCount{3} = TTCorrelation(app.timetagger, app.count3channelAEditField.Value, app.count3channelBEditField.Value, app.binwidthpsEditField.Value, app.binnumberEditField.Value);
            end
            if app.count3EnableCheckBox.Value
                app.correlationCount{4} = TTCorrelation(app.timetagger, app.count4channelAEditField.Value, app.count4channelBEditField.Value, app.binwidthpsEditField.Value, app.binnumberEditField.Value);
            end
        end
        
        % ************************************  update Countrate ***********************************************
        function updateCountrateTimerCallback(app)
            % updateTimerCallback is invoked by the updateTimer and polls the countrate.getData() to visualize the current countrate of each channel
            countrates = app.countrate.getData();
            app.countrate.clear();
            for i=1:length(countrates)
                app.countrate_{i}.Text = sprintf(app.formatString, round(countrates(i)));
            end
        end
        function updateCountrateVirtualsettingCallback(app)
            % 此函数用来更新虚拟通道的计数率。
            % 每次需要看新的虚拟通道计数率时，要调用一次此函数
            if isempty(app.virtualTableNumber)
                return
            end
            app.countrateVirtual = TTCountrate(app.timetagger, app.virtualTableNumber);
            app.countrateVirtualNumber = app.virtualTableNumber;
        end
        function updateCountrateVirtualCallback(app)
            % 更新虚拟通道计数率的函数，要放在timer函数的回调函数里被调用
            % 这里把测到的计数率都放到app的一个属性里，使用时从属性里调用相应的那个虚拟通道计数率。这样可以保证灵活性
            app.countrateVirtualRate = app.countrateVirtual.getData();
            app.countrateVirtual.clear();
        end
        
        % ****************************************************** replicate Element *************************************************
        %下面4个函数用来创建控件
        function replicateElement(app, templateName)
            % replicates gui elements which the user interacts with or which have to be modified
            eval(['template = app.' templateName 'Template;']);
            vector = cell(1, app.channels);
            vector{1} = template;
            for i=1:app.channels
                if i == 1
                    vector{i}.UserData = i;
                    continue
                end
                vector{i} = copyobj(vector{1}, vector{1}.Parent);
                vector{i}.UserData = i;
                
                if isprop(vector{1}, 'ValueChangedFcn')
                    vector{i}.ValueChangedFcn = template.ValueChangedFcn;
                end
                if isprop(vector{1}, 'ValueChangingFcn')
                    vector{i}.ValueChangingFcn = template.ValueChangingFcn;
                end
                
                vector{i}.Position(2) = vector{i-1}.Position(2) - app.LINEHEIGHT;
            end    
            eval(['app.' templateName ' = vector;']);
        end    
        function replicateElement2(app, templateName)
            % 二维的复制原件
            eval(['template = app.' templateName 'Template;']);
            vector = cell(app.channels, app.channels);
            vector{1,1} = template;
            for i=1:app.channels
                for j=1:app.channels
                    if i == 1 && j==1
                        vector{i,j}.UserData = [i,j];
                        continue
                    end
                    vector{i,j} = copyobj(vector{1,1}, vector{1,1}.Parent);
                    vector{i,j}.UserData = [i, j];
                    
                    if isprop(vector{1,1}, 'ValueChangedFcn')
                        vector{i,j}.ValueChangedFcn = template.ValueChangedFcn;
                    end
                    if isprop(vector{1,1}, 'ValueChangingFcn')
                        vector{i,j}.ValueChangingFcn = template.ValueChangingFcn;
                    end
                    
                    vector{i,j}.Position(2) = vector{1,1}.Position(2) - (i-1)*app.LINEHEIGHT;
                    vector{i,j}.Position(1) = vector{1,1}.Position(1) + (j-1)*app.LINEHEIGHT;
                end
            end    
            eval(['app.' templateName ' = vector;']);
        end
        function replicateConstantLabel(app, templateName)
            % replicates gui elements which are static
            eval(['template = app.' templateName 'Template;']);
            for i=2:app.channels
                newLabel = copyobj(template, template.Parent);
                newLabel.Position(2) = template.Position(2) - (i-1) * app.LINEHEIGHT;
                % the channel label needs to be changed according to the channel number
                if strcmp(templateName, 'channel_')
                    newLabel.Text = ['ch ', num2str(i+app.CH_OFF)];
                end
            end
        end
        
        function createUiElementsFromTemplate(app)
            allElements = {'triglvl_', 'delay_', 'deadtime_', 'testsignal_', 'countrate_'};
            for element = allElements
                replicateElement(app, element{1})
            end
            if app.flag_buttonMatrix
                replicateElement2(app, 'chButton_');
            end
            allLabels = {'channel_'};
            for element = allLabels
                replicateConstantLabel(app, element{1})
            end
        end
        
        % ************************************************ 产生虚拟通道 ******************************
        function addVirtualAnd(app, ch1, ch2, delay)
            % 添加虚拟通道，and运算时调用的函数
            if ch1 == ch2
                app.stateLabel.Text = 'Invalid adding command: the two channel number must be different.';
            else
                if app.autodelayCheckBox.Value
                    % 以后再添加此功能
                end
                namestr=['ch',num2str(ch1),'_and_ch',num2str(ch2),'_',num2str(delay)];
                app.virtuali=app.virtuali+1;
                app.virtualChannel_{app.virtuali} = TTCoincidence(app.timetagger, [ch1 ch2], delay);
                % eval([namestr,'=TTCoincidence(app.timetagger, [',num2str(ch1),' ',num2str(ch2),'], delay);']);
                channelnumber = app.virtualChannel_{app.virtuali}.getChannel();
                app.stateLabel.Text = ['new virtual channel: ',namestr,', channel number: ',num2str(channelnumber),', index: ',num2str(app.virtuali)];
                app.virtualTableIndex(app.virtuali) = app.virtuali;
                app.virtualTableNumber(app.virtuali) = channelnumber;
                app.virtualTableType(app.virtuali) = "coincidence";
                app.virtualTableName(app.virtuali) = string(namestr);
                
                app.VirtualchannelstateTextArea.Value = [app.stateLabel.Text; app.VirtualchannelstateTextArea.Value];
            end
            app.UITable.Data=[num2cell(app.virtualTableIndex)', num2cell(app.virtualTableNumber)', cellstr(app.virtualTableName)', cellstr(app.virtualTableType)'];
        end
        
        function addVirtualOr(app, ch1, ch2)
            % 添加虚拟通道，or运算时调用的函数
            if ch1 == ch2
                app.stateLabel.Text = 'Invalid adding command: the two channel number must be different.';
            else
                namestr=['ch',num2str(ch1),'_or_ch',num2str(ch2)];
                app.virtuali=app.virtuali+1;
                app.virtualChannel_{app.virtuali} = TTCombiner(app.timetagger, [ch1 ch2]);
                
                channelnumber= app.virtualChannel_{app.virtuali}.getChannel();
                app.stateLabel.Text = ['new virtual channel: ',namestr,', channel number: ',num2str(channelnumber),', index: ',num2str(app.virtuali)];
                app.virtualTableIndex(app.virtuali) = app.virtuali;
                app.virtualTableNumber(app.virtuali) = channelnumber;
                app.virtualTableType(app.virtuali) = "combiner";
                app.virtualTableName(app.virtuali) = string(namestr);
                
                app.VirtualchannelstateTextArea.Value = [app.stateLabel.Text; app.VirtualchannelstateTextArea.Value];
            end
            app.UITable.Data=[num2cell(app.virtualTableIndex)', num2cell(app.virtualTableNumber)', cellstr(app.virtualTableName)', cellstr(app.virtualTableType)'];
            
        end
        
        function addGatedVirtual(app, ch_ungated, ch_start, ch_stop)
            % 添加gate型虚拟通道
            if ch_start == ch_stop
                app.stateLabel.Text = 'Invalid command: Gate start and stop channel must not be the same.';
                return
            end
            namestr=['Gated_ch',num2str(ch_ungated),'_ch[',num2str(ch_start),'_',num2str(ch_stop),']'];
            app.virtuali=app.virtuali+1;
            app.virtualChannel_{app.virtuali} = TTGatedChannel(app.timetagger, ch_ungated, ch_start, ch_stop);
            channelnumber= app.virtualChannel_{app.virtuali}.getChannel();
            app.stateLabel.Text = ['new virtual channel: ',namestr,', channel number: ',num2str(channelnumber),', index: ',num2str(app.virtuali)];
            app.virtualTableIndex(app.virtuali) = app.virtuali;
            app.virtualTableNumber(app.virtuali) = channelnumber;
            app.virtualTableType(app.virtuali) = "gate";
            app.virtualTableName(app.virtuali) = string(namestr);
            app.VirtualchannelstateTextArea.Value = [app.stateLabel.Text; app.VirtualchannelstateTextArea.Value];
            app.UITable.Data=[num2cell(app.virtualTableIndex)', num2cell(app.virtualTableNumber)', cellstr(app.virtualTableName)', cellstr(app.virtualTableType)'];
        end
        
        function addDelayedVirtual(app, ch, delay)
            ind = find(app.virtualTableNumber==ch);
            if isempty(ind)        % 判断此通道是否是虚拟通道
                % 不是虚拟通道;建立新虚拟通道
                namestr=['delayed_ch',num2str(ch),'_',num2str(delay)];
                app.virtuali=app.virtuali+1;
                app.virtualChannel_{app.virtuali} = TTDelayedChannel(app.timetagger, ch, delay);
                % eval([namestr,'=TTDelayedChannel(app.timetagger, ch, delay);']);
                channelnumber= app.virtualChannel_{app.virtuali}.getChannel();
                app.stateLabel.Text = ['new virtual channel: ',namestr,', channel number: ',num2str(channelnumber),', index: ',num2str(app.virtuali)];
                app.virtualTableIndex(app.virtuali) = app.virtuali;
                app.virtualTableNumber(app.virtuali) = channelnumber;
                app.virtualTableType(app.virtuali) = "delay";
                app.virtualTableName(app.virtuali) = string(namestr);
                app.VirtualchannelstateTextArea.Value = [app.stateLabel.Text; app.VirtualchannelstateTextArea.Value];
            else
                namestr=app.virtualTableName(ind);
                if strcmp(app.virtualTableType(ind),"delay")
                    % 是虚拟通道且是delay型虚拟通道; 仅改变delay time
                    app.virtualChannel_{ind}.setDelay(delay);
                    app.stateLabel.Text = ['virtual channel ',char(namestr),' delay changed, channel number: ',num2str(app.virtualTableNumber(ind)),', index: ',num2str(ind)];
                    app.VirtualchannelstateTextArea.Value = [app.stateLabel.Text; app.VirtualchannelstateTextArea.Value];
                else
                    % 是虚拟通道但不是delay型虚拟通道：建立新的delay型虚拟通道
                    ch = app.virtualTableNumber(ind);
                    namestr=['delayed_ch',num2str(ch),'_',num2str(delay)];
                    app.virtuali=app.virtuali+1;
                    app.virtualChannel_{app.virtuali} = TTDelayedChannel(app.timetagger, ch, delay);
                    channelnumber= app.virtualChannel_{app.virtuali}.getChannel();
                    app.stateLabel.Text = ['new virtual channel: ',namestr,', channel number: ',num2str(channelnumber),', index: ',num2str(app.virtuali)];
                    app.virtualTableIndex(app.virtuali) = app.virtuali;
                    app.virtualTableNumber(app.virtuali) = channelnumber;
                    app.virtualTableType(app.virtuali) = "delay";
                    app.virtualTableName(app.virtuali) = string(namestr);
                    app.VirtualchannelstateTextArea.Value = [app.stateLabel.Text; app.VirtualchannelstateTextArea.Value];
                end
            end
            app.UITable.Data=[num2cell(app.virtualTableIndex)', num2cell(app.virtualTableNumber)', cellstr(app.virtualTableName)', cellstr(app.virtualTableType)'];
        end
        
        function commandRun(app,cmd)
            % 运行命令的函数
            cmd = deblank(cmd);     %去除尾空格
            % cmdecho=cmd;
            cmdcell=regexp(cmd, '\s+', 'split');        %分割命令
            cmdcellnum=length(cmdcell);
            switch char(cmdcell{1})
                
                case 'help'
                    helpstr=sprintf(['可用命令：\nhelp\n显示帮助',...
                        '\nset binwidth [ps]\n设置bin宽度，单位ps',...
                        '\nset binnumber [ps]\n设置bin数量',...
                        '\nset windowwidth [ps]\n设置窗口宽度（binwidth,binnumber和windowwidth中只有两个量是独立的',...
                        '\n\nset level [channel] [volt]\n设置某channel的trigger level',...
                        '\nset delay [channel] [ps]\n设置某通道的delay',...
                        '\nser deadtime [channel] [ps]\n设置某通道的deadtime（TimeTagger20型号的仪器最小值是6000 ps）',...
                        '\n\nadd and [ch1] [ch2] [delay/ps]\n由通道ch1和通道ch2生成一个新的and型虚拟通道，同时设置其相对延迟。（此delay不受setting界面的delay影响）',...
                        '\nadd or [ch1] [ch2]\n由通道ch1和通道ch2生成一个新的and型虚拟通道',...
                        '\nadd gate [ch] [ch_start] [ch_stop]\n生成一个gate型虚拟通道，由ch_start和ch_stop分别作为门控的起始和终点，获得在其范围内通道ch的计数，生成新虚拟通道。',...
                        '\nadd delay [ch] [delay/ps]\n生成一个delay型虚拟通道。']);
                    msgbox(helpstr,'command help');
                case 'add'
                    if cmdcellnum >= 2
                        switch char(cmdcell{2})
                            case 'and'
                                if cmdcellnum >= 4
                                    ch1 = str2double(char(cmdcell{3}));
                                    ch2 = str2double(char(cmdcell{4}));
                                    if cmdcellnum >= 5
                                        delay = str2double(char(cmdcell{5}));
                                    else
                                        delay = 0;
                                    end
                                    addVirtualAnd(app, ch1, ch2, delay);
                                else
                                    app.stateLabel.Text = 'Not enough parameters.';
                                end
                            case 'or'
                                if cmdcellnum >= 4
                                    ch1 = str2double(char(cmdcell{3}));
                                    ch2 = str2double(char(cmdcell{4}));
                                    addVirtualOr(app, ch1, ch2);
                                else
                                    app.stateLabel.Text = 'Not enough parameters.';
                                end
                            case 'gate'
                                if cmdcellnum >= 5
                                    ch_ungated = str2double(char(cmdcell{3}));
                                    ch_start = str2double(char(cmdcell{4}));
                                    ch_stop = str2double(char(cmdcell{5}));
                                    addGatedVirtual(app, ch_ungated, ch_start, ch_stop);
                                else
                                    app.stateLabel.Text = 'Not enough parameters.';
                                end
                            case 'delay'
                                if cmdcellnum >= 4
                                    ch = str2double(char(cmdcell{3}));
                                    delay = str2double(char(cmdcell{4}));
                                    addDelayedVirtual(app, ch, delay);
                                else
                                    app.stateLabel.Text = 'Not enough parameters.';
                                end
                        end
                    end
                case 'set'
                    if cmdcellnum >= 2
                        switch char(cmdcell{2})
                            case 'binwidth'
                                if cmdcellnum >= 3
                                    binwidth = str2double(char(cmdcell{3}));
                                    app.binwidthpsEditField.Value = binwidth;
                                    binwidthpsEditFieldValueChanged(app);
                                else
                                    app.stateLabel.Text = 'Not enough parameters.';
                                end
                            case 'binnumber'
                                if cmdcellnum >= 3
                                    binnumber = str2double(char(cmdcell{3}));
                                    app.binnumberEditField.Value = binnumber;
                                    binnumberEditFieldValueChanged(app);
                                else
                                    app.stateLabel.Text = 'Not enough parameters.';
                                end
                            case 'windowwidth'
                                if cmdcellnum >= 3
                                    windowwidth = str2double(char(cmdcell{3}));
                                    app.windowwidthnsEditField.Value = windowwidth;
                                    windowwidthnsEditFieldValueChanged(app);
                                else
                                    app.stateLabel.Text = 'Not enough parameters.';
                                end
                            case 'level'
                                if cmdcellnum >= 4
                                    ch = str2double(char(cmdcell{3}));
                                    level = str2double(char(cmdcell{4}));
                                    app.triglvl_{ch - app.CH_OFF}.Value = level;
                                else
                                    app.stateLabel.Text = 'Not enough parameters.';
                                end
                            case 'delay'
                                if cmdcellnum >= 4
                                    ch = str2double(char(cmdcell{3}));
                                    delay = str2double(char(cmdcell{4}));
                                    app.delay_{ch - app.CH_OFF}.Value = delay;
                                else
                                    app.stateLabel.Text = 'Not enough parameters.';
                                end
                            case 'deadtime'
                                if cmdcellnum >= 4
                                    ch = str2double(char(cmdcell{3}));
                                    deadtime = str2double(char(cmdcell{4}));
                                    app.deadtime_{ch - app.CH_OFF}.Value = deadtime;
                                else
                                    app.stateLabel.Text = 'Not enough parameters.';
                                end
                                
                        end
                    end
                case 'delete'
                    if cmdcellnum ==2 && strcmp(char(cmdcell{2}), 'all')
                        delAllVirtual(app);
                        app.stateLabel.Text = 'All virtual channel deleted.';
                        app.VirtualchannelstateTextArea.Value = [app.stateLabel.Text; app.VirtualchannelstateTextArea.Value];
                    end
            end
        end
        
        function delAllVirtual(app)
            % delete all the virtual channels
            timerflag = strcmp(app.theTimer.Running, 'on');
            stop(app.theTimer)
            
            for i = 1: app.virtuali
                %             app.countrateVirtual = [];
                app.virtualChannel_{i}.delete();
            end
            
            app.virtuali = 0;                % 虚拟通道index
            app.countrateVirtualNumber=[];   % countratrvirtual对象的通道数寄存变量
            app.countrateVirtualRate=[];     % countratrvirtual对象的计数率寄存变量
            app.timeoutCount =0;             % 统计超时次数
            app.virtualTableIndex=[];          % all virtual channel's index
            app.virtualTableNumber=[];         % all virtual channel's number.
            app.virtualTableType="";        % all virtual channel's type.
            app.virtualTableName="";        % all virtual channel's name
            
            app.UITable.Data=[num2cell(app.virtualTableIndex)', num2cell(app.virtualTableNumber)', cellstr(app.virtualTableName)', cellstr(app.virtualTableType)'];
            updateCorrelationSettings(app);
            updateCountCorrelationSettings(app);
            updateCountrateVirtualsettingCallback(app);
            
            if timerflag
                start(app.theTimer)
            end
        end
        
        function timelog(app)
            % log运行时的函数,被timer调用
            app.x_time = [app.x_time, now];
            ch = app.countrateofchannelEditField.Value;
            app.y_countrate = [app.y_countrate, str2double(app.countrate_{ch+1}.Text)];
            plot(app.logaxes, app.x_time, app.y_countrate);
        end


    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            initialization(app);
            
            if app.f_connection==1         % 连接仪器成功才会创建timer对象
                app.theTimer = timer('StartDelay', 1, 'Period', app.PeriodsEditField.Value, 'TasksToExecute', Inf, 'ExecutionMode', 'fixedRate');
                app.theTimer.TimerFcn = @(obj, event)updatetimerCallback(app);
                start(app.theTimer);
            end
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            ch1=app.addchannelAEditField.Value;
            ch2=app.addchannelBEditField.Value;
            if strcmp(app.addmodeDropDown.Value, 'and')
                addVirtualAnd(app, ch1, ch2, app.windowEditField.Value);
            elseif strcmp(app.addmodeDropDown.Value, 'or')
                addVirtualOr(app, ch1, ch2);
            end
            
        end

        % Button pushed function: AddButton_2
        function AddButton_2Pushed(app, event)
            ch_ungated=app.channeltogateEditField.Value;
            ch_start = app.gatestartchannelEditField.Value;
            ch_stop = app.gatestopchannelEditField.Value;
            addGatedVirtual(app, ch_ungated, ch_start, ch_stop);
        end

        % Button pushed function: AddButton_3
        function AddButton_3Pushed(app, event)
            ch=app.channeltodelayEditField.Value;
            delay = app.addchangedelayEditField.Value;
            addDelayedVirtual(app, ch, delay);
        end

        % Button pushed function: ClearoverflowButton
        function ClearoverflowButtonPushed(app, event)
            app.timetagger.clearOverflows();
        end

        % Value changed function: CommandWindowEditField
        function CommandWindowEditFieldValueChanged(app, event)
            % 命令窗口的回调
            cmd = app.CommandWindowEditField.Value;
            commandRun(app,cmd);
        end

        % Button pushed function: HelpButton
        function HelpButtonPushed(app, event)
            % 打开帮助txt文档
            fid = fopen( 'help.txt','wt');
            fprintf(fid,'timetagger符合仪帮助文档\r\n');
            fprintf(fid,'version: 1.0\r\n\r\n');
            fprintf(fid,'此程序适用于timetagger 20 和timetagger ultra\r\n');
            fprintf(fid,'界面左侧设置bin宽度，bin数目，和窗口宽度。这三个参数只有两个是独立的。\r\n');
            fprintf(fid,'上边的start/stop按钮控制程序内部循环，旁边的Period设置循环周期。循环周期过小可能会使计数不准确，如果这种情况发生，setting页面左下角会有time out提示。\r\n');
            fprintf(fid,'clear和save按钮暂时不可用\r\n');
            fprintf(fid,'overflows指示灯为红色表示存在丢数据的情况，考虑降低输入仪器的计数\r\n\r\n');
            fprintf(fid,'setting页面：\r\n');
            fprintf(fid,'左上角显示仪器连接信息和仪器通道数\r\n');
            fprintf(fid,'各个通道的delay会影响plot页面的图，但不会影响and运算的虚拟通道的delay\r\n');
            fprintf(fid,'deadtime必须是最小值（默认值）的整数倍\r\n');
            fprintf(fid,'save settings按钮会把bin设置和虚拟通道的设置保存在同目录下的txt文件中\r\n');
            fprintf(fid,'使用load settings按钮回复这些设置，以简化重启软件时的操作。（未来版本可能允许保存更多的设置参数）\r\n');
            fprintf(fid,'测试信号复选框选中后，仪器会在内部产生0.8MHz的脉冲信号\r\n\r\n');
            fprintf(fid,'plot页面：\r\n');
            fprintf(fid,'通道数：以8通道符合仪为例，0-7通道是这8个通道的上升沿，8-15通道是对应的下降沿，从16开始，每生成一个虚拟通道，依次占用一个通道号。\r\n');
            fprintf(fid,'single plot：横轴单位ps，纵轴单位count/period。 channel A 和channel B的时间前后逻辑可能是反的，但这里依然按照原顺序画图\r\n');
            fprintf(fid,'multi plot： 复选框选中后才会运行，其作图逻辑与single相同，都受左边bin设置控制。\r\n');
            fprintf(fid,'\r\ncount页面：\r\n');
            fprintf(fid,'每一个panel中都提供了两个通道符合的功能，四个panel功能相同。（如果4个panel显示同样的channel设置，在period短的时候示数可能不同；这是由于运行顺序不同导致的）\r\n');
            fprintf(fid,'每个panel的countrate标签提供了查看虚拟通道计数率的功能。下面较大的数字显示的是符合计数。\r\n');
            fprintf(fid,'符合计数实际上就是plot页面中曲线最大值。因此让此计数是真正的符合计数，要求符合峰必须在窗口内，且bin宽度足够大，delay合适，让计数都在一个bin中。\r\n');
            fprintf(fid,'\r\n虚拟通道页面\r\n');
            fprintf(fid,'add virtual channel panel：增加两通道的and或or逻辑的一个虚拟通道。and逻辑要求delay合适，bin够宽才会有理想的二路符合效果。\r\n');
            fprintf(fid,'delay的设定可先在plot single里看图像与零点的时差，再写到这里。or逻辑delay无效。auto delay功能暂时没有加入。\r\n');
            fprintf(fid,'delay channel panel：产生一个新的虚拟通道，与原通道有个可调的延迟。delay只能是正数，负数会当做0处理。\r\n');
            fprintf(fid,'如果这里的输入通道是一个delay型的虚拟通道，那么只会更改此通道的delay时间；如果是真实通道或其他类型虚拟通道，则会产生一个新的虚拟通道，与原通道有特定的延迟。\r\n');
            fprintf(fid,'gate channel panel：只保留start和stop通道脉冲之间的某一通道的计数，生成一个新的虚拟通道。\r\n');
            fprintf(fid,'add 中两个通道不能相同，gate中start和stop不能相同。\r\n');
            fprintf(fid,'命令窗口的用法，在命令窗口中键入help来查看。\r\n');
            fprintf(fid,'2018年9月\r\n');
            fclose(fid);
            system('help.txt');
            
        end

        % Button pushed function: LoadsettingsButton
        function LoadsettingsButtonPushed(app, event)
            [filename, pathname] = uigetfile( '*.txt');
            fid = fopen( [pathname, filename], 'rt');
            ind=0;
            while feof(fid) ~= 1                %用于判断文件指针p在其所指的文件中的位置，如果到文件末，函数返回1，否则返回0
                ind = ind + 1;
                cmds(ind) = string(fgetl(fid));
            end
            fclose(fid);
            for i=1:ind
                cmd = char(cmds(i));
                commandRun(app,cmd);
            end
            
        end

        % Value changed function: PeriodsEditField
        function PeriodsEditFieldValueChanged(app, event)
            Value = app.PeriodsEditField.Value;
            stop(app.theTimer);
            app.theTimer.Period = Value;
            app.timeoutCount = 0;
            app.timeoutLabel.Visible = 'off';
            app.timeoutLabel.Text = ['time out number: ', num2str(app.timeoutCount),', time out rate: ',num2str(round(app.timeoutCount/app.theTimer.TasksExecuted*100)),'%'];
            start(app.theTimer);
        end

        % Button pushed function: SavesettingsButton
        function SavesettingsButtonPushed(app, event)
            ds = datestr(datetime, 'yyyymmddHHMMSS');
            fid = fopen(['settings',ds,'.txt'], 'a');
            % 保存trigger level，delay和deadtime设定
            for i = 1:app.channels
                fprintf(fid,'\r\nset level %d %d', i+app.CH_OFF, app.triglvl_{i}.Value);
            end
            for i = 1:app.channels
                fprintf(fid, '\r\nset delay %d %d', i+app.CH_OFF, app.delay_{i}.Value);
            end
            for i = 1:app.channels
                fprintf(fid, '\r\nset deadtime %d %d', i+app.CH_OFF, app.deadtime_{i}.Value);
            end
            
            %保存bin设置
            fprintf(fid,'\r\nset binwidth %d \r\nset binnumber %d \r\nset windowwidth %d',...
                app.binwidthpsEditField.Value,...
                app.binnumberEditField.Value,...
                app.windowwidthnsEditField.Value);
            %保存虚拟通道设置
            for i = app.virtualTableIndex
                namechars = char(app.virtualTableName(i));
                if strcmp(app.virtualTableType(i),"coincidence")
                    chs = sscanf(namechars, 'ch%d_and_ch%d_%d');
                    fprintf(fid,'\r\nadd and %d %d %d',chs(1),chs(2),chs(3));
                elseif strcmp(app.virtualTableType(i),"combiner")
                    chs = sscanf(namechars, 'ch%d_or_ch%d');
                    fprintf(fid,'\r\nadd or %d %d',chs(1),chs(2));
                elseif strcmp(app.virtualTableType(i),"gate")
                    chs = sscanf(namechars, 'Gated_ch%d_ch[%d_%d]');
                    fprintf(fid,'\r\nadd gate %d %d %d',chs(1),chs(2),chs(3));
                elseif strcmp(app.virtualTableType(i),"delay")
                    chs = sscanf(namechars, 'delayed_ch%d_%d');
                    fprintf(fid,'\r\nadd delay %d %d',chs(1),chs(2));
                end
            end
            fclose(fid);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if ~isempty(app.theTimer)
                stop(app.theTimer)
                delete(app.theTimer)
            end
            if app.f_connection == 1
                app.timetagger.free();
            end
            delete(app);
            
        end

        % Value changed function: addmodeDropDown
        function addmodeDropDownValueChanged(app, event)
            value = app.addmodeDropDown.Value;
            if strcmp(value,'and')
                app.autodelayCheckBox.Value = 1;
            elseif strcmp(value,'or')
                app.autodelayCheckBox.Value = 0;
            end
        end

        % Value changed function: autosaveButton
        function autosaveButtonValueChanged(app, event)
            value = app.autosaveButton.Value;
            if app.PeriodsEditField.Value < 5 && value
                app.autosaveButton.Value = 0;
                msgbox('The autosave function could enabled only when Period > 5s');
            end
            % autosaveindex: when
            if app.autosaveButton.Value == 0
                app.autoSaveIndex = 0;
            elseif app.autosaveButton.Value && ~app.autoSaveIndex
                app.autoSaveIndex = 1;
            end
        end

        % Value changed function: autoyaxisCheckBox
        function autoyaxisCheckBoxValueChanged(app, event)
            value = app.autoyaxisCheckBox.Value;
            if value
                app.ymaxEditField.Visible = 'off';
                app.ymaxLabel.Visible = 'off';
            else
                app.ymaxEditField.Visible = 'on';
                app.ymaxLabel.Visible = 'on';
            end
        end

        % Value changed function: binnumberEditField
        function binnumberEditFieldValueChanged(app, event)
            value = app.binnumberEditField.Value;
            app.windowwidthnsEditField.Value = value * app.binwidthpsEditField.Value / 1000;
            updateCorrelationSettings(app);
            updateCountCorrelationSettings(app);
        end

        % Value changed function: binwidthpsEditField
        function binwidthpsEditFieldValueChanged(app, event)
            value = app.binwidthpsEditField.Value;
            app.windowwidthnsEditField.Value = value * app.binnumberEditField.Value / 1000;
            updateCorrelationSettings(app);
            updateCountCorrelationSettings(app);
            
        end

        % Value changed function: chButton_Template
        function chButton_TemplateValueChanged(app, event)
            % 通道按钮阵列的回调函数。
            if app.flag_buttonMatrix
                ind = event.Source.UserData;
                app.channelMatrix(ind(1),ind(2))=event.Source.Value;
            end
        end

        % Value changed function: channelAEditField
        function channelAEditFieldValueChanged(app, event)
            updateCorrelationSettings(app);
        end

        % Value changed function: channelBEditField
        function channelBEditFieldValueChanged(app, event)
            updateCorrelationSettings(app);
        end

        % Button pushed function: clearlogButton
        function clearlogButtonPushed(app, event)
            app.x_time = [];
            app.y_countrate = [];
        end

        % Value changed function: count1EnableCheckBox
        function count1EnableCheckBoxValueChanged(app, event)
            updateCountCorrelationSettings(app)
        end

        % Value changed function: count1channelAEditField
        function count1channelAEditFieldValueChanged(app, event)
            updateCountCorrelationSettings(app);
        end

        % Value changed function: count1channelBEditField
        function count1channelBEditFieldValueChanged(app, event)
            updateCountCorrelationSettings(app);
        end

        % Value changed function: count2EnableCheckBox
        function count2EnableCheckBoxValueChanged(app, event)
            updateCountCorrelationSettings(app);
        end

        % Value changed function: count2channelAEditField
        function count2channelAEditFieldValueChanged(app, event)
            updateCountCorrelationSettings(app);
        end

        % Value changed function: count2channelBEditField
        function count2channelBEditFieldValueChanged(app, event)
            updateCountCorrelationSettings(app);
        end

        % Value changed function: count3EnableCheckBox
        function count3EnableCheckBoxValueChanged(app, event)
            updateCountCorrelationSettings(app);
        end

        % Value changed function: count3channelAEditField
        function count3channelAEditFieldValueChanged(app, event)
            updateCountCorrelationSettings(app);
        end

        % Value changed function: count3channelBEditField
        function count3channelBEditFieldValueChanged(app, event)
            updateCountCorrelationSettings(app);
        end

        % Value changed function: count4EnableCheckBox
        function count4EnableCheckBoxValueChanged(app, event)
            updateCountCorrelationSettings(app);
        end

        % Value changed function: count4channelAEditField
        function count4channelAEditFieldValueChanged(app, event)
            updateCountCorrelationSettings(app);
        end

        % Value changed function: count4channelBEditField
        function count4channelBEditFieldValueChanged(app, event)
            updateCountCorrelationSettings(app);
        end

        % Value changed function: deadtime_Template
        function deadtime_TemplateValueChanged(app, event)
            value = event.Source.Value;
            actualDeadtime = app.timetagger.setDeadtime(event.Source.UserData + app.CH_OFF, value);
            % the deadtime can be only a multiple of the inverse clock frequency of the Time Tagger. Therefore the actual value can be different from the value typed in. The real deadtime in the input field.
            event.Source.Value = double(actualDeadtime);
        end

        % Value changed function: delay_Template
        function delay_TemplateValueChanged(app, event)
            value = event.Source.Value;
            app.timetagger.setInputDelay(event.Source.UserData + app.CH_OFF, value);
        end

        % Value changed function: multiplotenableCheckBox
        function multiplotenableCheckBoxValueChanged(app, event)
            value = app.multiplotenableCheckBox.Value;
            if value == 1
                updateCorrelationSettings(app);
            end
        end

        % Button pushed function: saveButton
        function saveButtonPushed(app, event)
            % 保存数据
            formatOut = 'yyyy-mm-dd_HH-MM-SS';
            time = datetime;
            ds = datestr(time, formatOut);
            CorrelationMeasurement = app.displayCorrelationMeasurement;
            uisave({'CorrelationMeasurement'}, strcat('./data/Correlation_', ds));
        end

        % Button pushed function: startButton
        function startButtonPushed(app, event)
            if strcmp(app.theTimer.Running, 'off')
                start(app.theTimer);
            end
        end

        % Button pushed function: startlogButton
        function startlogButtonPushed(app, event)
            app.flag_log = 1;
        end

        % Button pushed function: stopButton
        function stopButtonPushed(app, event)
            stop(app.theTimer);
            app.timeoutCount = 0;
            app.timeoutLabel.Visible = 'off';
            app.timeoutLabel.Text = ['time out number: ', num2str(app.timeoutCount),', time out rate: ',num2str(round(app.timeoutCount/app.theTimer.TasksExecuted*100)),'%'];
        end

        % Button pushed function: stoplogButton
        function stoplogButtonPushed(app, event)
            app.flag_log = 0;
        end

        % Value changed function: testsignal_Template
        function testsignal_TemplateValueChanged(app, event)
            app.timetagger.setTestSignal(event.Source.UserData + app.CH_OFF, event.Source.Value);
        end

        % Value changed function: triglvl_Template
        function triglvl_TemplateValueChanged(app, event)
            value = event.Source.Value;
            app.timetagger.setTriggerLevel(event.Source.UserData + app.CH_OFF, value);
            % app.timetagger.getTriggerLevel(event.Source.UserData + app.CH_OFF)
        end

        % Value changed function: windowwidthnsEditField
        function windowwidthnsEditFieldValueChanged(app, event)
            value = app.windowwidthnsEditField.Value * 1000;
            binnumber = ceil(value / app.binwidthpsEditField.Value);
            valuereal = binnumber * app.binwidthpsEditField.Value;
            if valuereal ~= value
                app.windowwidthnsEditField.Value = valuereal / 1000;
            end
            app.binnumberEditField.Value = binnumber;
            updateCorrelationSettings(app);
            updateCountCorrelationSettings(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.settingsTab = uitab(app.TabGroup);
            app.settingsTab.Title = 'settings';
            app.n_channels = uilabel(app.settingsTab);
            app.n_channels.Text = 'number of channel';
            app.n_serial = uilabel(app.settingsTab);
            app.n_serial.Text = 'Serial number:                  ';
            app.channel_Template = uilabel(app.settingsTab);
            app.channel_Template.Text = 'ch 0';
            app.triglvl_Template = uieditfield(app.settingsTab, 'numeric');
            app.triglvl_Template.ValueChangedFcn = createCallbackFcn(app, @triglvl_TemplateValueChanged, true);
            app.triglvl_Template.Limits = [0 5];
            app.triglvl_Template.Value = 0.5;
            app.delay_Template = uieditfield(app.settingsTab, 'numeric');
            app.delay_Template.ValueChangedFcn = createCallbackFcn(app, @delay_TemplateValueChanged, true);
            app.delay_Template.RoundFractionalValues = 'on';
            app.deadtime_Template = uieditfield(app.settingsTab, 'numeric');
            app.deadtime_Template.LowerLimitInclusive = 'off';
            app.deadtime_Template.ValueChangedFcn = createCallbackFcn(app, @deadtime_TemplateValueChanged, true);
            app.deadtime_Template.Limits = [0 Inf];
            app.deadtime_Template.RoundFractionalValues = 'on';
            app.deadtime_Template.Value = 1;
            app.testsignal_Template = uicheckbox(app.settingsTab);
            app.testsignal_Template.ValueChangedFcn = createCallbackFcn(app, @testsignal_TemplateValueChanged, true);
            app.testsignal_Template.Text = '';
            app.ChannelLabel = uilabel(app.settingsTab);
            app.ChannelLabel.Text = 'Channel';
            app.TriggerlevelVLabel = uilabel(app.settingsTab);
            app.TriggerlevelVLabel.Text = 'Trigger level / V';
            app.DelaypsLabel = uilabel(app.settingsTab);
            app.DelaypsLabel.Text = 'Delay / ps';
            app.DeadtimepsLabel = uilabel(app.settingsTab);
            app.DeadtimepsLabel.Text = 'Dead time / ps';
            app.testsignalLabel = uilabel(app.settingsTab);
            app.testsignalLabel.Text = 'testsignal';
            app.messageLabel = uilabel(app.settingsTab);
            app.messageLabel.Text = 'message';
            app.countratecpsLabel = uilabel(app.settingsTab);
            app.countratecpsLabel.Text = 'countrate / cps';
            app.countrate_Template = uilabel(app.settingsTab);
            app.countrate_Template.Text = '0';
            app.timeoutLabel = uilabel(app.settingsTab);
            app.timeoutLabel.Visible = 'off';
            app.timeoutLabel.Text = 'time out';
            app.SavesettingsButton = uibutton(app.settingsTab, 'push');
            app.SavesettingsButton.ButtonPushedFcn = createCallbackFcn(app, @SavesettingsButtonPushed, true);
            app.SavesettingsButton.Text = 'Save settings';
            app.LoadsettingsButton = uibutton(app.settingsTab, 'push');
            app.LoadsettingsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadsettingsButtonPushed, true);
            app.LoadsettingsButton.Text = 'Load settings';
            app.HelpButton = uibutton(app.settingsTab, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.HelpButton.Text = 'Help';
            app.plotTab = uitab(app.TabGroup);
            app.plotTab.Title = 'plot';
            app.TabGroup2 = uitabgroup(app.plotTab);
            app.singleTab = uitab(app.TabGroup2);
            app.singleTab.Title = 'single';
            app.channelAEditFieldLabel = uilabel(app.singleTab);
            app.channelAEditFieldLabel.Text = 'channel A';
            app.channelAEditField = uieditfield(app.singleTab, 'numeric');
            app.channelAEditField.ValueChangedFcn = createCallbackFcn(app, @channelAEditFieldValueChanged, true);
            app.channelAEditField.Limits = [0 Inf];
            app.channelAEditField.RoundFractionalValues = 'on';
            app.channelAEditField.ValueDisplayFormat = '%.0f';
            app.channelBEditFieldLabel = uilabel(app.singleTab);
            app.channelBEditFieldLabel.Text = 'channel B';
            app.channelBEditField = uieditfield(app.singleTab, 'numeric');
            app.channelBEditField.ValueChangedFcn = createCallbackFcn(app, @channelBEditFieldValueChanged, true);
            app.channelBEditField.Limits = [0 Inf];
            app.channelBEditField.RoundFractionalValues = 'on';
            app.channelBEditField.ValueDisplayFormat = '%.0f';
            app.channelBEditField.Value = 1;
            app.singleAxes = uiaxes(app.singleTab);
            title(app.singleAxes, 'Title')
            xlabel(app.singleAxes, 'X')
            ylabel(app.singleAxes, 'Y')
            app.maximumLabel = uilabel(app.singleTab);
            app.maximumLabel.Text = 'maximum';
            app.MaxvalueLabel = uilabel(app.singleTab);
            app.MaxvalueLabel.Text = 'Max value :';
            app.autoyaxisCheckBox = uicheckbox(app.singleTab);
            app.autoyaxisCheckBox.ValueChangedFcn = createCallbackFcn(app, @autoyaxisCheckBoxValueChanged, true);
            app.autoyaxisCheckBox.Text = 'auto y axis';
            app.autoyaxisCheckBox.Value = true;
            app.ymaxLabel = uilabel(app.singleTab);
            app.ymaxLabel.Text = 'y max:';
            app.ymaxEditField = uieditfield(app.singleTab, 'numeric');
            app.ymaxEditField.Limits = [1 Inf];
            app.ymaxEditField.Value = 100;
            app.PositonpsLabel = uilabel(app.singleTab);
            app.PositonpsLabel.Text = 'Positon /ps:';
            app.positionLabel = uilabel(app.singleTab);
            app.positionLabel.Text = 'position';
            app.multiTab = uitab(app.TabGroup2);
            app.multiTab.Title = 'multi';
            app.UIAxes1 = uiaxes(app.multiTab);
            title(app.UIAxes1, 'Title')
            xlabel(app.UIAxes1, 'X')
            ylabel(app.UIAxes1, 'Y')
            app.UIAxes2 = uiaxes(app.multiTab);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.UIAxes3 = uiaxes(app.multiTab);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            app.UIAxes4 = uiaxes(app.multiTab);
            title(app.UIAxes4, 'Title')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            app.chA1EditFieldLabel = uilabel(app.multiTab);
            app.chA1EditFieldLabel.Text = 'ch A1';
            app.chA1EditField = uieditfield(app.multiTab, 'numeric');
            app.chA1EditField.Limits = [0 Inf];
            app.chA1EditField.RoundFractionalValues = 'on';
            app.chB1EditFieldLabel = uilabel(app.multiTab);
            app.chB1EditFieldLabel.Text = 'ch B1';
            app.chB1EditField = uieditfield(app.multiTab, 'numeric');
            app.chB1EditField.Limits = [0 Inf];
            app.chB1EditField.RoundFractionalValues = 'on';
            app.chA2EditFieldLabel = uilabel(app.multiTab);
            app.chA2EditFieldLabel.Text = 'ch A2';
            app.chA2EditField = uieditfield(app.multiTab, 'numeric');
            app.chA2EditField.Limits = [0 Inf];
            app.chA2EditField.RoundFractionalValues = 'on';
            app.chB2EditFieldLabel = uilabel(app.multiTab);
            app.chB2EditFieldLabel.Text = 'ch B2';
            app.chB2EditField = uieditfield(app.multiTab, 'numeric');
            app.chB2EditField.Limits = [0 Inf];
            app.chB2EditField.RoundFractionalValues = 'on';
            app.chA3EditFieldLabel = uilabel(app.multiTab);
            app.chA3EditFieldLabel.Text = 'ch A3';
            app.chA3EditField = uieditfield(app.multiTab, 'numeric');
            app.chA3EditField.Limits = [0 Inf];
            app.chA3EditField.RoundFractionalValues = 'on';
            app.chB3EditFieldLabel = uilabel(app.multiTab);
            app.chB3EditFieldLabel.Text = 'ch B3';
            app.chB3EditField = uieditfield(app.multiTab, 'numeric');
            app.chB3EditField.Limits = [0 Inf];
            app.chB3EditField.RoundFractionalValues = 'on';
            app.chA4EditFieldLabel = uilabel(app.multiTab);
            app.chA4EditFieldLabel.Text = 'ch A4';
            app.chA4EditField = uieditfield(app.multiTab, 'numeric');
            app.chA4EditField.Limits = [0 Inf];
            app.chA4EditField.RoundFractionalValues = 'on';
            app.chB4EditFieldLabel = uilabel(app.multiTab);
            app.chB4EditFieldLabel.Text = 'ch B4';
            app.chB4EditField = uieditfield(app.multiTab, 'numeric');
            app.chB4EditField.Limits = [0 Inf];
            app.chB4EditField.RoundFractionalValues = 'on';
            app.multiplotenableCheckBox = uicheckbox(app.multiTab);
            app.multiplotenableCheckBox.ValueChangedFcn = createCallbackFcn(app, @multiplotenableCheckBoxValueChanged, true);
            app.multiplotenableCheckBox.Text = 'multiplot enable';
            app.countsTab = uitab(app.TabGroup);
            app.countsTab.Title = 'counts';
            app.chButton_Template = uibutton(app.countsTab, 'state');
            app.chButton_Template.ValueChangedFcn = createCallbackFcn(app, @chButton_TemplateValueChanged, true);
            app.chButton_Template.Text = '';
            app.Panel1 = uipanel(app.countsTab);
            app.Panel1.Title = 'Panel 1';
            app.channelAEditField_2Label = uilabel(app.Panel1);
            app.channelAEditField_2Label.Text = 'channel A';
            app.count1channelAEditField = uieditfield(app.Panel1, 'numeric');
            app.count1channelAEditField.ValueChangedFcn = createCallbackFcn(app, @count1channelAEditFieldValueChanged, true);
            app.channelBEditField_2Label = uilabel(app.Panel1);
            app.channelBEditField_2Label.Text = 'channel B';
            app.count1channelBEditField = uieditfield(app.Panel1, 'numeric');
            app.count1channelBEditField.ValueChangedFcn = createCallbackFcn(app, @count1channelBEditFieldValueChanged, true);
            app.count1rateA = uilabel(app.Panel1);
            app.count1rateA.Text = 'countrate';
            app.count1rateB = uilabel(app.Panel1);
            app.count1rateB.Text = 'countrate';
            app.count1coincidence = uilabel(app.Panel1);
            app.count1coincidence.Text = '0';
            app.count1EnableCheckBox = uicheckbox(app.Panel1);
            app.count1EnableCheckBox.ValueChangedFcn = createCallbackFcn(app, @count1EnableCheckBoxValueChanged, true);
            app.count1EnableCheckBox.Text = 'Enable';
            app.Panel2 = uipanel(app.countsTab);
            app.Panel2.Title = 'Panel 2';
            app.channelAEditField_2Label_2 = uilabel(app.Panel2);
            app.channelAEditField_2Label_2.Text = 'channel A';
            app.count2channelAEditField = uieditfield(app.Panel2, 'numeric');
            app.count2channelAEditField.ValueChangedFcn = createCallbackFcn(app, @count2channelAEditFieldValueChanged, true);
            app.channelBEditField_2Label_2 = uilabel(app.Panel2);
            app.channelBEditField_2Label_2.Text = 'channel B';
            app.count2channelBEditField = uieditfield(app.Panel2, 'numeric');
            app.count2channelBEditField.ValueChangedFcn = createCallbackFcn(app, @count2channelBEditFieldValueChanged, true);
            app.count2rateA = uilabel(app.Panel2);
            app.count2rateA.Text = 'countrate';
            app.count2rateB = uilabel(app.Panel2);
            app.count2rateB.Text = 'countrate';
            app.count2coincidence = uilabel(app.Panel2);
            app.count2coincidence.Text = '0';
            app.count2EnableCheckBox = uicheckbox(app.Panel2);
            app.count2EnableCheckBox.ValueChangedFcn = createCallbackFcn(app, @count2EnableCheckBoxValueChanged, true);
            app.count2EnableCheckBox.Text = 'Enable';
            app.Panel3 = uipanel(app.countsTab);
            app.Panel3.Title = 'Panel 3';
            app.channelAEditField_2Label_3 = uilabel(app.Panel3);
            app.channelAEditField_2Label_3.Text = 'channel A';
            app.count3channelAEditField = uieditfield(app.Panel3, 'numeric');
            app.count3channelAEditField.ValueChangedFcn = createCallbackFcn(app, @count3channelAEditFieldValueChanged, true);
            app.channelBEditField_2Label_3 = uilabel(app.Panel3);
            app.channelBEditField_2Label_3.Text = 'channel B';
            app.count3channelBEditField = uieditfield(app.Panel3, 'numeric');
            app.count3channelBEditField.ValueChangedFcn = createCallbackFcn(app, @count3channelBEditFieldValueChanged, true);
            app.count3rateA = uilabel(app.Panel3);
            app.count3rateA.Text = 'countrate';
            app.count3rateB = uilabel(app.Panel3);
            app.count3rateB.Text = 'countrate';
            app.count3coincidence = uilabel(app.Panel3);
            app.count3coincidence.Text = '0';
            app.count3EnableCheckBox = uicheckbox(app.Panel3);
            app.count3EnableCheckBox.ValueChangedFcn = createCallbackFcn(app, @count3EnableCheckBoxValueChanged, true);
            app.count3EnableCheckBox.Text = 'Enable';
            app.Panel4 = uipanel(app.countsTab);
            app.Panel4.Title = 'Panel 4';
            app.channelAEditField_2Label_4 = uilabel(app.Panel4);
            app.channelAEditField_2Label_4.Text = 'channel A';
            app.count4channelAEditField = uieditfield(app.Panel4, 'numeric');
            app.count4channelAEditField.ValueChangedFcn = createCallbackFcn(app, @count4channelAEditFieldValueChanged, true);
            app.channelBEditField_2Label_4 = uilabel(app.Panel4);
            app.channelBEditField_2Label_4.Text = 'channel B';
            app.count4channelBEditField = uieditfield(app.Panel4, 'numeric');
            app.count4channelBEditField.ValueChangedFcn = createCallbackFcn(app, @count4channelBEditFieldValueChanged, true);
            app.count4rateA = uilabel(app.Panel4);
            app.count4rateA.Text = 'countrate';
            app.count4rateB = uilabel(app.Panel4);
            app.count4rateB.Text = 'countrate';
            app.count4coincidence = uilabel(app.Panel4);
            app.count4coincidence.Text = '0';
            app.count4EnableCheckBox = uicheckbox(app.Panel4);
            app.count4EnableCheckBox.ValueChangedFcn = createCallbackFcn(app, @count4EnableCheckBoxValueChanged, true);
            app.count4EnableCheckBox.Text = 'Enable';
            app.virtualchannelTab = uitab(app.TabGroup);
            app.virtualchannelTab.Title = 'virtual channel';
            app.VirtualchannelstateTextAreaLabel = uilabel(app.virtualchannelTab);
            app.VirtualchannelstateTextAreaLabel.Text = 'Virtual channel state';
            app.VirtualchannelstateTextArea = uitextarea(app.virtualchannelTab);
            app.CommandWindowEditFieldLabel = uilabel(app.virtualchannelTab);
            app.CommandWindowEditFieldLabel.Text = 'Command Window';
            app.CommandWindowEditField = uieditfield(app.virtualchannelTab, 'text');
            app.CommandWindowEditField.ValueChangedFcn = createCallbackFcn(app, @CommandWindowEditFieldValueChanged, true);
            app.AddVirtualchannelPanel = uipanel(app.virtualchannelTab);
            app.AddVirtualchannelPanel.Title = 'Add Virtual channel';
            app.addmodeDropDownLabel = uilabel(app.AddVirtualchannelPanel);
            app.addmodeDropDownLabel.Text = 'add mode';
            app.addmodeDropDown = uidropdown(app.AddVirtualchannelPanel);
            app.addmodeDropDown.Items = {'and', 'or'};
            app.addmodeDropDown.ValueChangedFcn = createCallbackFcn(app, @addmodeDropDownValueChanged, true);
            app.addmodeDropDown.Value = 'and';
            app.addchannelAEditFieldLabel = uilabel(app.AddVirtualchannelPanel);
            app.addchannelAEditFieldLabel.Text = 'add channel A';
            app.addchannelAEditField = uieditfield(app.AddVirtualchannelPanel, 'numeric');
            app.addchannelAEditField.Limits = [0 Inf];
            app.addchannelAEditField.RoundFractionalValues = 'on';
            app.addchannelBEditFieldLabel = uilabel(app.AddVirtualchannelPanel);
            app.addchannelBEditFieldLabel.Text = 'add channel B';
            app.addchannelBEditField = uieditfield(app.AddVirtualchannelPanel, 'numeric');
            app.addchannelBEditField.Limits = [0 Inf];
            app.addchannelBEditField.RoundFractionalValues = 'on';
            app.autodelayCheckBox = uicheckbox(app.AddVirtualchannelPanel);
            app.autodelayCheckBox.Text = 'auto delay';
            app.AddButton = uibutton(app.AddVirtualchannelPanel, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add';
            app.windowEditFieldLabel = uilabel(app.AddVirtualchannelPanel);
            app.windowEditFieldLabel.Text = 'window';
            app.windowEditField = uieditfield(app.AddVirtualchannelPanel, 'numeric');
            app.windowEditField.RoundFractionalValues = 'on';
            app.GatedVirtualchannelPanel = uipanel(app.virtualchannelTab);
            app.GatedVirtualchannelPanel.Title = 'Gated Virtual channel';
            app.gatestartchannelEditFieldLabel = uilabel(app.GatedVirtualchannelPanel);
            app.gatestartchannelEditFieldLabel.Text = 'gate start channel';
            app.gatestartchannelEditField = uieditfield(app.GatedVirtualchannelPanel, 'numeric');
            app.gatestartchannelEditField.Limits = [0 Inf];
            app.gatestartchannelEditField.RoundFractionalValues = 'on';
            app.gatestopchannelEditFieldLabel = uilabel(app.GatedVirtualchannelPanel);
            app.gatestopchannelEditFieldLabel.Text = 'gate stop channel';
            app.gatestopchannelEditField = uieditfield(app.GatedVirtualchannelPanel, 'numeric');
            app.gatestopchannelEditField.Limits = [0 Inf];
            app.gatestopchannelEditField.RoundFractionalValues = 'on';
            app.AddButton_2 = uibutton(app.GatedVirtualchannelPanel, 'push');
            app.AddButton_2.ButtonPushedFcn = createCallbackFcn(app, @AddButton_2Pushed, true);
            app.AddButton_2.Text = 'Add';
            app.channeltogateEditFieldLabel = uilabel(app.GatedVirtualchannelPanel);
            app.channeltogateEditFieldLabel.Text = 'channel to gate';
            app.channeltogateEditField = uieditfield(app.GatedVirtualchannelPanel, 'numeric');
            app.channeltogateEditField.Limits = [0 Inf];
            app.channeltogateEditField.RoundFractionalValues = 'on';
            app.DelayedchannelPanel = uipanel(app.virtualchannelTab);
            app.DelayedchannelPanel.Title = 'Delayed channel';
            app.channeltodelayEditFieldLabel = uilabel(app.DelayedchannelPanel);
            app.channeltodelayEditFieldLabel.Text = 'channel to delay';
            app.channeltodelayEditField = uieditfield(app.DelayedchannelPanel, 'numeric');
            app.channeltodelayEditField.Limits = [0 Inf];
            app.channeltodelayEditField.RoundFractionalValues = 'on';
            app.addchangedelayEditFieldLabel = uilabel(app.DelayedchannelPanel);
            app.addchangedelayEditFieldLabel.Text = 'add/change delay';
            app.addchangedelayEditField = uieditfield(app.DelayedchannelPanel, 'numeric');
            app.addchangedelayEditField.RoundFractionalValues = 'on';
            app.AddButton_3 = uibutton(app.DelayedchannelPanel, 'push');
            app.AddButton_3.ButtonPushedFcn = createCallbackFcn(app, @AddButton_3Pushed, true);
            app.AddButton_3.Text = 'Add';
            app.stateLabel = uilabel(app.virtualchannelTab);
            app.stateLabel.Text = 'state';
            app.CommandfeedbackLabel = uilabel(app.virtualchannelTab);
            app.CommandfeedbackLabel.Text = 'Command feedback:';
            app.UITable = uitable(app.virtualchannelTab);
            app.UITable.ColumnName = {'index'; 'channel'; 'name'; 'type'};
            app.UITable.RowName = {};
            app.LogTab = uitab(app.TabGroup);
            app.LogTab.Title = 'Log';
            app.startlogButton = uibutton(app.LogTab, 'push');
            app.startlogButton.ButtonPushedFcn = createCallbackFcn(app, @startlogButtonPushed, true);
            app.startlogButton.Text = 'start log';
            app.clearlogButton = uibutton(app.LogTab, 'push');
            app.clearlogButton.ButtonPushedFcn = createCallbackFcn(app, @clearlogButtonPushed, true);
            app.clearlogButton.Text = 'clear log';
            app.stoplogButton = uibutton(app.LogTab, 'push');
            app.stoplogButton.ButtonPushedFcn = createCallbackFcn(app, @stoplogButtonPushed, true);
            app.stoplogButton.Text = 'stop log';
            app.logaxes = uiaxes(app.LogTab);
            title(app.logaxes, 'Title')
            xlabel(app.logaxes, 'X')
            ylabel(app.logaxes, 'Y')
            app.countrateofchannelEditFieldLabel = uilabel(app.LogTab);
            app.countrateofchannelEditFieldLabel.Text = 'countrate of channel:';
            app.countrateofchannelEditField = uieditfield(app.LogTab, 'numeric');
            app.OverflowsLamp = uilamp(app.UIFigure);
            app.ClearoverflowButton = uibutton(app.UIFigure, 'push');
            app.ClearoverflowButton.ButtonPushedFcn = createCallbackFcn(app, @ClearoverflowButtonPushed, true);
            app.ClearoverflowButton.Text = 'Clear overflow';
            app.OverflowsnumberLabel = uilabel(app.UIFigure);
            app.OverflowsnumberLabel.Text = 'Overflows number';
            app.startButton = uibutton(app.UIFigure, 'push');
            app.startButton.ButtonPushedFcn = createCallbackFcn(app, @startButtonPushed, true);
            app.startButton.Text = 'start';
            app.stopButton = uibutton(app.UIFigure, 'push');
            app.stopButton.ButtonPushedFcn = createCallbackFcn(app, @stopButtonPushed, true);
            app.stopButton.Text = 'stop';
            app.saveButton = uibutton(app.UIFigure, 'push');
            app.saveButton.ButtonPushedFcn = createCallbackFcn(app, @saveButtonPushed, true);
            app.saveButton.Text = 'save';
            app.PeriodsEditFieldLabel = uilabel(app.UIFigure);
            app.PeriodsEditFieldLabel.Text = 'Period / s';
            app.PeriodsEditField = uieditfield(app.UIFigure, 'numeric');
            app.PeriodsEditField.LowerLimitInclusive = 'off';
            app.PeriodsEditField.ValueChangedFcn = createCallbackFcn(app, @PeriodsEditFieldValueChanged, true);
            app.PeriodsEditField.Limits = [0 1000000];
            app.PeriodsEditField.Value = 1;
            app.binwidthpsEditFieldLabel = uilabel(app.UIFigure);
            app.binwidthpsEditFieldLabel.Text = 'bin width / ps';
            app.binwidthpsEditField = uieditfield(app.UIFigure, 'numeric');
            app.binwidthpsEditField.ValueChangedFcn = createCallbackFcn(app, @binwidthpsEditFieldValueChanged, true);
            app.binwidthpsEditField.Limits = [1 1000000000000];
            app.binwidthpsEditField.RoundFractionalValues = 'on';
            app.binwidthpsEditField.Value = 1000;
            app.binnumberEditFieldLabel = uilabel(app.UIFigure);
            app.binnumberEditFieldLabel.Text = 'bin number';
            app.binnumberEditField = uieditfield(app.UIFigure, 'numeric');
            app.binnumberEditField.ValueChangedFcn = createCallbackFcn(app, @binnumberEditFieldValueChanged, true);
            app.binnumberEditField.Limits = [1 1000000000000];
            app.binnumberEditField.RoundFractionalValues = 'on';
            app.binnumberEditField.Value = 100;
            app.windowwidthnsEditFieldLabel = uilabel(app.UIFigure);
            app.windowwidthnsEditFieldLabel.Text = 'window width / ns';
            app.windowwidthnsEditField = uieditfield(app.UIFigure, 'numeric');
            app.windowwidthnsEditField.ValueChangedFcn = createCallbackFcn(app, @windowwidthnsEditFieldValueChanged, true);
            app.windowwidthnsEditField.Limits = [0 Inf];
            app.windowwidthnsEditField.Value = 100;
            app.autosaveButton = uibutton(app.UIFigure, 'state');
            app.autosaveButton.ValueChangedFcn = createCallbackFcn(app, @autosaveButtonValueChanged, true);
            app.autosaveButton.Text = 'auto save';
        end
    end

    methods (Access = public)

        % Construct app
        function app = timetagger01

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end